classdef CA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        mean_analysis    matlab.ui.control.Button
        Correlated_plot  matlab.ui.control.UIAxes
        RightPanel       matlab.ui.container.Panel
        except           matlab.ui.control.Button
        CityTable        matlab.ui.control.Table
        Label            matlab.ui.control.Label
        mean             matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        low              matlab.ui.control.EditField
        save             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: mean_analysis
        function mean_analysisButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx';'*.xls'},'File Selector');
            filepath=strcat(path,file);
            Data = readtable(filepath);
            Place = Data(:,1);
            Place =table2array(Place);
            lY = Place;
            Y = categorical(Place);
            Y = reordercats(Y,Place);
            Datasize=size(Data);
            Data =Data(:,2:Datasize(2));
            Data = table2array(Data);
            X=Data;
            msgbox("导入数据成功，正在计算，请稍后");
            
            %%  第二步：判断是否需要正向化
            [n,~] = size(X);
            X = Min2Max(X);
            %% 第三步：对正向化后的矩阵进行标准化
            Z = X ./ repmat(sum(X.*X) .^ 0.5, n, 1);
            %% 让用户判断是否需要增加权重
            weight = Entropy_Method(Z);
            %% 第四步：计算与最大值的距离和最小值的距离，并算出得分
            D_P = sum([(Z - repmat(max(Z),n,1)) .^ 2 ] .* repmat(weight,n,1) ,2) .^ 0.5;   % D+ 与最大值的距离向量
            D_N = sum([(Z - repmat(min(Z),n,1)) .^ 2 ] .* repmat(weight,n,1) ,2) .^ 0.5;   % D- 与最小值的距离向量
            S = D_N ./ (D_P+D_N);    % 未归一化的得分
            % disp('最后的得分为：')
            stand_S = S / sum(S)+0.01;
            
            figure;
            bar(app.Correlated_plot,Y,stand_S);
            boxplot(stand_S);
            title('环境指数箱线图')
            xlabel('数据情况')
            ylabel('环境指数')
            
            [~,index] = sort(stand_S);
            meanstand_s = mean(stand_S);
            app.mean.Value = meanstand_s;
            
            lownumber = 0;
            for i = 1:n
                if stand_S(i)<=meanstand_s
                    lownumber =lownumber + 1;
                end
            end
            
            app.low.Value = num2str(lownumber);
            
            order = 1:n;
            order = flip(order);
            order =order';
            order = num2cell(order);
            nX=stand_S(index);
            nX = num2cell(nX);
            nY=lY(index);
            nY = cell(nY);
            
            XY = [nY nX order];
%             XY = str2cell(XY);
            %
            %             [~,index] = sort(stand_S);
            
            app.CityTable.Data=XY;
            %
            %             [~,index] = sort(stand_S);
            

        end

        % Button pushed function: except
        function exceptPushed(app, event)
            run Forecasting.mlapp
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            writecell(app.CityTable.Data,'Compare files');
            msgbox("保存成功");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '环境评价及预测系统';
            app.mean_analysis = uibutton(app.UIFigure, 'push');
            app.mean_analysis.ButtonPushedFcn = createCallbackFcn(app, @mean_analysisButtonPushed, true);
            app.mean_analysis.Text = '计算';
            app.Correlated_plot = uiaxes(app.UIFigure);
            title(app.Correlated_plot, '多地环境指数对比图')
            xlabel(app.Correlated_plot, '地名')
            ylabel(app.Correlated_plot, '环境指数')
            app.RightPanel = uipanel(app.UIFigure);
            app.RightPanel.Title = '多地城市排名';
            app.except = uibutton(app.RightPanel, 'push');
            app.except.ButtonPushedFcn = createCallbackFcn(app, @exceptPushed, true);
            app.except.Text = '城市环境未来预测';
            app.CityTable = uitable(app.RightPanel);
            app.CityTable.ColumnName = {'城市'; '分数'; '排名'};
            app.CityTable.RowName = {};
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '平均值';
            app.mean = uieditfield(app.RightPanel, 'numeric');
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '低于平均值数量';
            app.low = uieditfield(app.RightPanel, 'text');
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = '保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end