classdef ESS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnvironmentalScoringSystem  matlab.ui.Figure
        Input                       matlab.ui.control.Button
        Output                      matlab.ui.control.Table
        Title                       matlab.ui.control.Label
        NewPage                     matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        save                        matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EnvironmentalScoringSystem, Input
        function InputButtonPushed(app, event)
        %上传文件
        
        [file,path] = uigetfile({'*.xlsx';'*.xls'},'File Selector');
        filepath=strcat(path,file);
        Data = xlsread(filepath,'sheet1');
        Datasize=size(Data);
        X=Data;
        
        msgbox("导入数据成功，正在计算，请稍后");
        %%  第二步：判断是否需要正向化
        [n,~] = size(X);
        %% 第三步：对正向化后的矩阵进行标准化
        Z = X ./ repmat(sum(X.*X) .^ 0.5, n, 1);
        %% 让用户判断是否需要增加权重
        weight = Entropy_Method(Z);
        %% 第四步：计算与最大值的距离和最小值的距离，并算出得分
        D_P = sum([(Z - repmat(max(Z),n,1)) .^ 2 ] .* repmat(weight,n,1) ,2) .^ 0.5;   % D+ 与最大值的距离向量
        D_N = sum([(Z - repmat(min(Z),n,1)) .^ 2 ] .* repmat(weight,n,1) ,2) .^ 0.5;   % D- 与最小值的距离向量
        S = D_N ./ (D_P+D_N);    % 未归一化的得分
        % disp('最后的得分为：')
        stand_S = S / sum(S);
%         stand_S = 1./stand_S;
        
        pathname = '.\';
        filename = 'stand_S.mat';
        filepath2=strcat(pathname,filename);
        save(filepath2,'stand_S');
        
        [~,index] = sort(stand_S ,'descend');
        
        dates=Data(:,1);
        result = [dates index stand_S];
        result = sortrows(result,2);
        A=result(:,1);
        B=result(:,2);
        C=result(:,3);
        
        plot(app.UIAxes,dates,stand_S);
        
        R=[A B C];
        
        app.Output.Data=R;
        end

        % Button pushed function: NewPage
        function NewPagePushed(app, event)
        %运行相关性分析页面
        run CA.mlapp
        end

        % Callback function
        function forecastingButtonPushed(app, event)
         run Forecasting.mlapp
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
                 writematrix(app.Output.Data,'Development file');
                msgbox("保存成功");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnvironmentalScoringSystem = uifigure('Visible', 'off');
            app.EnvironmentalScoringSystem.Name = '环境评价及预测系统';
            app.EnvironmentalScoringSystem.ButtonDownFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.Input = uibutton(app.EnvironmentalScoringSystem, 'push');
            app.Input.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.Input.Text = '计算分数';
            app.Output = uitable(app.EnvironmentalScoringSystem);
            app.Output.ColumnName = {'日期'; '排名'; '得分'};
            app.Output.RowName = {};
            app.Title = uilabel(app.EnvironmentalScoringSystem);
            app.Title.Text = '环境评价及预测系统';
            app.NewPage = uibutton(app.EnvironmentalScoringSystem, 'push');
            app.NewPage.ButtonPushedFcn = createCallbackFcn(app, @NewPagePushed, true);
            app.NewPage.Text = '多地分析';
            app.Panel = uipanel(app.EnvironmentalScoringSystem);
            app.Panel.Title = '环境指数走势图';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '目标城市环境指数走势图')
            xlabel(app.UIAxes, '年代')
            ylabel(app.UIAxes, '指数')
            app.save = uibutton(app.EnvironmentalScoringSystem, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = '保存';
            % Show the figure after all components are created
            app.EnvironmentalScoringSystem.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnvironmentalScoringSystem)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnvironmentalScoringSystem)
        end
    end
end