classdef Forecasting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        GreyForecasting     matlab.ui.control.Button
        NetWorkForecasting  matlab.ui.control.Button
        warninglabe         matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        save                matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        forecasteplot       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {673, 673};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: GreyForecasting
        function GreyForecastingButtonPushed(app, event)
    
            [file,path] = uigetfile({'*.xlsx';'*.xls'},'File Selector');
            filepath=strcat(path,file);
            XJData = xlsread(filepath);
            
            msgbox("导入数据成功，正在计算，请稍后");
            X1 =XJData(:,3);
            Xp1 = X1;
            
            numsize = size (X1);
            numsize = numsize(1);
            
           
            plot(app.forecasteplot,X1,'--')
            hold(app.forecasteplot,'on');
            
            timelong = 1:numsize;
            timelong =timelong';
            
            p = polyfit(timelong,X1,2);
            plot(app.forecasteplot,polyval(p,timelong))
            hold(app.forecasteplot,'on');
            
            
            X2 =XJData(:,4);
            Xp2 = X2;
            plot(app.forecasteplot,X2,'-.')
            hold(app.forecasteplot,'on');
            
            p = polyfit(timelong,X2,2);
            plot(app.forecasteplot,polyval(p,timelong))
            hold(app.forecasteplot,'on');
            
            X3 =XJData(:,5);
            Xp3 = X3;
            plot(app.forecasteplot,X3,'b')
            hold(app.forecasteplot,'on');
            
            p = polyfit(timelong,X3,2);
            plot(app.forecasteplot,polyval(p,timelong))
            hold(app.forecasteplot,'on');


            ten1 = flip(Xp1);
            ten2 = flip(Xp2);
            ten3 = flip(Xp3);
            tennum = 1:12;
            ten1 = ten1(tennum);
            ten2 = ten2(tennum);
            ten3 = ten3(tennum);
            
            figure;
            [Xp1,cp1,errorp1,errorp2]=GM11(ten1,3);
            scalength=1:3;
            plot(scalength,Xp1(13:15));
            hold on;
            
            [Xp2,cp1,errorp1,errorp2]=GM11(ten2,3);
            
            plot(scalength,Xp2(13:15));
            hold on;
            
            [Xp3,cp1,errorp1,errorp2]=GM11(ten3,3);
    
            plot(scalength,Xp3(13:15));
            hold on;
            title("未来三个月发展趋势")
            legend('预测PM2.5趋势','预测PM10趋势','预测AQI趋势');

            
            Date = 1:3;
            
            Xs = [Date' Xp1(13:15)' Xp2(13:15)' Xp3(13:15)'];
            
            Xs = flipud(Xs);
            app.UITable.Data =Xs;
            legend(app.forecasteplot,'PM2.5值','PM2.5趋势','PM10值','PM10趋势','AQI值','AQI趋势');
        end

        % Button pushed function: NetWorkForecasting
        function NetWorkForecastingButtonPushed(app, event)

            [file,path] = uigetfile({'*.xlsx';'*.xls'},'File Selector');
            filepath=strcat(path,file);
            XJData = xlsread(filepath);
            
            msgbox("导入数据成功，正在计算，请稍后");
            X1 =XJData(:,3);
            
            numsize = size (X1);
            numsize = numsize(1);
            
            Xp1 = X1(1:numsize);
            
            plot(app.forecasteplot,X1,'--')
            hold(app.forecasteplot,'on');
            
            timelong = 1:numsize;
            timelong =timelong';
            
            p = polyfit(timelong,X1,2);
            plot(app.forecasteplot,polyval(p,timelong))
            hold(app.forecasteplot,'on');
            
            
            X2 =XJData(:,4);
            Xp2 = X2(1:numsize);
            plot(app.forecasteplot,X2,'-.')
            hold(app.forecasteplot,'on');
            
            p = polyfit(timelong,X2,2);
            plot(app.forecasteplot,polyval(p,timelong))
            hold(app.forecasteplot,'on');
            
            X3 =XJData(:,5);
            Xp3 = X3(1:numsize);
            plot(app.forecasteplot,X3,'b')
            hold(app.forecasteplot,'on');
            
            p = polyfit(timelong,X3,2);
            plot(app.forecasteplot,polyval(p,timelong))
            hold(app.forecasteplot,'on');
            
            legend(app.forecasteplot,'PM2.5值','PM2.5趋势','PM10值','PM10趋势','AQI值','AQI趋势');
            
            trainX=1:numsize;
            % trainX = trainX';
            trainY=[Xp1,Xp2,Xp3];
            trainY = trainY';
            
            [trainX,p]=mapminmax(trainX);
            [trainY,q]=mapminmax(trainY);
            
            net = newff(trainX, trainY,[5 5]);
            net.trainParam.lr = 0.02;%0.02
            net.trainParam.epochs = 25;%500,5000
            net.trainParam.goal = 0.00001;
            net = train(net,trainX,trainY);
            %训练数据预测
            BPoutput=sim(net,trainX);
            BPoutput=mapminmax('reverse',BPoutput,q);
            
            Days = numsize+1:(numsize + 3);
            BPDays=sim(net,Days);
            BPDays=mapminmax('reverse',BPDays,q);
            BPDays =BPDays';
            figure;
            plot(BPDays(:,1),'-.');
            hold on;
            plot(BPDays(:,2),'--');
            hold on;
            plot(BPDays(:,3));
            hold on;
            legend('PM2.5预测值','PM10值预测值','AQI值预测值');
            title("环境质量预测");
            
            orderday = 1:3;
            BPDays = [orderday' BPDays];
            app.UITable.Data =BPDays;
            
        
            %预测误差
%             erroryc=trainY-BPoutput;
%             BPoutput = BPoutput';
%             figure;
%             plot(BPoutput(:,1))
%             hold on;
%             plot(BPoutput(:,2))
%             hold on;
%             plot(BPoutput(:,3))
%             hold on;
%             
%             plot(X1)
%             hold on;
%             plot(X2)
%             hold on;
%             plot(X3)
%             hold on;
            
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
                writematrix(app.UITable.Data,'forecast file');
                msgbox("保存成功");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '环境评价及预测系统';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GreyForecasting = uibutton(app.LeftPanel, 'push');
            app.GreyForecasting.ButtonPushedFcn = createCallbackFcn(app, @GreyForecastingButtonPushed, true);
            app.GreyForecasting.Text = '灰色预测';
            app.NetWorkForecasting = uibutton(app.LeftPanel, 'push');
            app.NetWorkForecasting.ButtonPushedFcn = createCallbackFcn(app, @NetWorkForecastingButtonPushed, true);
            app.NetWorkForecasting.Text = '神经网络预测';
            app.warninglabe = uilabel(app.LeftPanel);
            app.warninglabe.Text = {'注意：小样本请使用灰色预测按钮'; '样本量高于50请启动神经网络进行'; '预测。'; '上述表格中正数表示将来的月份，'; '负数表示过去的月份。'};
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'月份'; 'PM2.5'; 'PM10'; 'AQI'};
            app.UITable.RowName = {};
            app.save = uibutton(app.LeftPanel, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = '保存';
            app.RightPanel = uipanel(app.GridLayout);
            app.forecasteplot = uiaxes(app.RightPanel);
            title(app.forecasteplot, '整体趋势')
            xlabel(app.forecasteplot, '时间')
            ylabel(app.forecasteplot, '指数')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forecasting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end